classdef WebCamPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BackButton         matlab.ui.control.Button
        CloseWebcamButton  matlab.ui.control.Button
        DetectEyesButton   matlab.ui.control.Button
        DetectFaceButton   matlab.ui.control.Button
        StartWEbcamButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
         cam; % Description
        loopFlag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartWEbcamButton
        function StartWebCam(app, event)
             
     % cam = app.cam;
     app.cam = webcam;
     app.loopFlag = true;

while true
    if app.loopFlag == false
        
        break;
    else
        e = snapshot(app.cam);
        imshow(e, 'Parent', app.UIAxes); % Utilisez l'objet d'axe graphique comme parent pour afficher l'image
        drawnow();
    end
end
clear app.cam;
        
        end

        % Button pushed function: DetectFaceButton
        function Face(app, event)
     
    % app.cam = webcam;
    % app.loopFlag = true;
    FDetect = vision.CascadeObjectDetector();
  

    % Boucle principale
    while true
        if app.loopFlag == false
            break;
        else
            % Capture de l'image
           e = snapshot(app.cam);
            
           v = rgb2gray(e);
            
            % Détection des visages
            BB = step(FDetect, v);
            
            % Insertion des annotations sur l'image
             pic =  insertShape(e, 'rectangle', BB);
            % pic =  insertObjectAnnotation(e, 'rectangle', BB,'face');
            
            % Affichage de l'image dans l'interface
            imshow(pic, 'Parent', app.UIAxes);
            drawnow;
        end
    end


        end

        % Button pushed function: DetectEyesButton
        function Eyes(app, event)
    FDetect = vision.CascadeObjectDetector('EyePairBig');    
    % Boucle principale
    while true
        if ~app.loopFlag
            break;
        else
            % Capture de l'image
            e = snapshot(app.cam);
            [width, ~] = size(e);

           if width>320
           e = imresize(e,[320 NaN]);
           end
           v = rgb2gray(e);
            
            % Détection des visages
            BB = step(FDetect, v);
            
            % Insertion des annotations sur l'image
            pic =  insertObjectAnnotation(e, 'rectangle', BB,'Eyes');
            
            % Affichage de l'image dans l'interface
            imshow(pic, 'Parent', app.UIAxes);
            
            % Forcer l'actualisation de l'affichage
            drawnow;
        end
    end


        end

        % Callback function
        function Nose(app, event)
         
    FDetect = vision.CascadeObjectDetector('Nose');    
    % Boucle principale
    while true
        if ~app.loopFlag
            break;
        else
            % Capture de l'image
            e = snapshot(app.cam);
            [width, ~] = size(e);

           if width>320
           e = imresize(e,[320 NaN]);
           end
           v = rgb2gray(e);
            
            % Détection des visages
            BB = step(FDetect, v);
            
            % Insertion des annotations sur l'image
            pic =  insertObjectAnnotation(e, 'rectangle', BB,'Nose');
            
            % Affichage de l'image dans l'interface
            imshow(pic, 'Parent', app.UIAxes);
            
            % Forcer l'actualisation de l'affichage
            drawnow;
        end
    end
 
        end

        % Button pushed function: CloseWebcamButton
        function close(app, event)
    app.loopFlag = false; % Arrêter la boucle de capture vidéo
    % cla(app.UIAxes);
    
    ss = imread("thankk.jpg"); % Charger l'image face3.jpg
    imshow(ss, 'Parent', app.UIAxes); 
    
        end

        % Button pushed function: BackButton
        function back(app, event)
            % close(app.UIFigure);
            % drawnow;
            uicontrol(MenuPage,"Parent");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.StartWEbcamButton = uibutton(app.UIFigure, 'push');
            app.StartWEbcamButton.ButtonPushedFcn = createCallbackFcn(app, @StartWebCam, true);
            app.StartWEbcamButton.Text = 'Start WEbcam';
            app.DetectFaceButton = uibutton(app.UIFigure, 'push');
            app.DetectFaceButton.ButtonPushedFcn = createCallbackFcn(app, @Face, true);
            app.DetectFaceButton.Text = 'Detect Face';
            app.DetectEyesButton = uibutton(app.UIFigure, 'push');
            app.DetectEyesButton.ButtonPushedFcn = createCallbackFcn(app, @Eyes, true);
            app.DetectEyesButton.Text = 'Detect Eyes';
            app.CloseWebcamButton = uibutton(app.UIFigure, 'push');
            app.CloseWebcamButton.ButtonPushedFcn = createCallbackFcn(app, @close, true);
            app.CloseWebcamButton.Text = 'Close Webcam';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @back, true);
            app.BackButton.Text = '>>Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebCamPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end