classdef photoPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BackButton_2           matlab.ui.control.Button
        UploadanimageButton_2  matlab.ui.control.Button
        DetectFaceButton       matlab.ui.control.Button
        removetheimageButton   matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pic; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadanimageButton_2
        function upload(app, event)
            [file,path]=uigetfile('*.*','Select image');
            loc=strcat(path,file);
            app.pic=imread(loc);
            [width, height] = size(app.pic);
            if width>330
            app.pic = imresize(app.pic,[330 NaN]);
            end
            imshow(app.pic,"Parent", app.UIAxes);
        end

        % Button pushed function: DetectFaceButton
        function FaceDetect(app, event)
             pic2 = rgb2gray(app.pic);
            ff=vision.CascadeObjectDetector();
            bbox=step(ff,pic2);
            dd= insertShape(app.pic,'Rectangle',bbox);
            imshow(dd,"Parent", app.UIAxes);
        end

        % Callback function
        function remove(app, event)


        end

        % Button pushed function: BackButton_2
        function back(app, event)
            % close(app.UIFigure);
            % drawnow;
            uicontrol(MenuPage,"Parent");
        end

        % Button pushed function: removetheimageButton
        function move(app, event)
            % cla(app.UIAxes);
            cla(app.UIAxes);
            % reset(app.UIAxes);
            drawnow;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.removetheimageButton = uibutton(app.UIFigure, 'push');
            app.removetheimageButton.ButtonPushedFcn = createCallbackFcn(app, @move, true);
            app.removetheimageButton.Text = 'remove the image';
            app.DetectFaceButton = uibutton(app.UIFigure, 'push');
            app.DetectFaceButton.ButtonPushedFcn = createCallbackFcn(app, @FaceDetect, true);
            app.DetectFaceButton.Text = 'Detect Face';
            app.UploadanimageButton_2 = uibutton(app.UIFigure, 'push');
            app.UploadanimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @upload, true);
            app.UploadanimageButton_2.Text = 'Upload an image';
            app.BackButton_2 = uibutton(app.UIFigure, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @back, true);
            app.BackButton_2.Text = '>>Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = photoPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end