classdef videoPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        StopthevideoButton    matlab.ui.control.Button
        BackButton            matlab.ui.control.Button
        RemovethevideoButton  matlab.ui.control.Button
        DetecetFaceButton     matlab.ui.control.Button
        UploadavideoButton    matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        vidObj; % Description
        stopFlag;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadavideoButton
        function video(app, event)
   [file, path] = uigetfile('*.*', 'Select Video File');
    % disp(['User selected ', fullfile(path,file)]);
    %Displaying Video on Axes
    input_video_file = fullfile(path,file);
    app.vidObj = VideoReader(input_video_file);
    
    app.stopFlag = true;

     while(hasFrame(app.vidObj)) 
         if ~app.stopFlag
            break;
        else
        frame = readFrame(app.vidObj);
        imshow(frame, 'Parent', app.UIAxes);   
        pause(2/app.vidObj.FrameRate);
         end
     end
       

        end

        % Button pushed function: DetecetFaceButton
        function face(app, event)
     
    face_Detector = vision.CascadeObjectDetector();
    
    while hasFrame(app.vidObj)
        if ~app.stopFlag
            break;
        else       
    video_Frame = readFrame(app.vidObj);

    % Obtenez les dimensions d'origine de la vidéo
    originalWidth = size(video_Frame, 2);
    originalHeight = size(video_Frame, 1);

    % Définir les nouvelles dimensions pour la vidéo redimensionnée
    newWidth = 320;
    newHeight = NaN;
    
    % Redimensionner le frame à l'aide de la fonction imresize
    resized_Frame = imresize(video_Frame, [newHeight, newWidth]);
    
    % Détecter les visages dans le frame redimensionné
    location_of_the_Face = step(face_Detector, resized_Frame);
    
    % Vérifier s'il y a au moins un visage détecté
    if ~isempty(location_of_the_Face)
        % Dessiner des rectangles autour des visages détectés
      detected_Frame = insertShape(resized_Frame, 'Rectangle', location_of_the_Face);
      imshow(detected_Frame, 'Parent', app.UIAxes);  
      pause(2/app.vidObj.FrameRate);

    end
    
    
        end
    end

           
        end

        % Callback function
        function remove(app, event)
           app.stopFlag = false; % Arrêter la boucle de capture vidéo
           cla(app.UIAxes);
 

        end

        % Button pushed function: BackButton
        function back(app, event)
            % close(app.UIFigure);
            % drawnow;
            uicontrol(MenuPage,"Parent");
        end

        % Button pushed function: RemovethevideoButton
        function vidmove(app, event)
            app.stopFlag = false; % Arrêter la boucle de capture vidéo
           cla(app.UIAxes);
        end

        % Button pushed function: StopthevideoButton
        function stop(app, event)
           app.stopFlag = false; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UploadavideoButton = uibutton(app.UIFigure, 'push');
            app.UploadavideoButton.ButtonPushedFcn = createCallbackFcn(app, @video, true);
            app.UploadavideoButton.Text = 'Upload a video';
            app.DetecetFaceButton = uibutton(app.UIFigure, 'push');
            app.DetecetFaceButton.ButtonPushedFcn = createCallbackFcn(app, @face, true);
            app.DetecetFaceButton.Text = 'Detecet Face';
            app.RemovethevideoButton = uibutton(app.UIFigure, 'push');
            app.RemovethevideoButton.ButtonPushedFcn = createCallbackFcn(app, @vidmove, true);
            app.RemovethevideoButton.Text = 'Remove the video';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @back, true);
            app.BackButton.Text = '>>Back';
            app.StopthevideoButton = uibutton(app.UIFigure, 'push');
            app.StopthevideoButton.ButtonPushedFcn = createCallbackFcn(app, @stop, true);
            app.StopthevideoButton.Text = 'Stop the video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = videoPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end